classdef gradientDescentVisualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LossPlot                 matlab.ui.control.UIAxes
        IterationPlot            matlab.ui.control.UIAxes
        LearningRateSliderLabel  matlab.ui.control.Label
        LearningRateSlider       matlab.ui.control.Slider
        LearnRateEdit            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        hLossFunction % Description
        const
        xData
        yData
        hLossStems
        hLossIter
        Iters
        Loss
        isRunning
        pauseTime
    end
    
    methods (Access = private)
        
        function initPlots(app)


            app.hLossFunction = plot(app.LossPlot,app.xData,app.yData);
            set(app.hLossFunction,'LineWidth',3)
            hold(app.LossPlot);
            
            app.hLossIter = plot(app.IterationPlot,1:5,1:5);
            set(app.hLossIter,'XData',[],'YData',[]);
            set(app.hLossIter,'LineWidth',3)
            
            app.IterationPlot.XLim = [0 60];
            app.IterationPlot.YLim = [0 5];


        end


        function initApp(app)
            
            app.pauseTime = 0.1;
            
            app.xData = linspace(-5,5,101);
            app.const = 0.3;
            app.yData = app.xData.^2 + app.const * randn(size(app.xData));
            app.yData(51) = app.const;
            
            app.initPlots();
            
        end
        
        function updateLossIteration(app)
            set(app.hLossIter,'XData',app.Iters,'YData',app.Loss);
            
        end
        
        function run(app)
            
            % start it up
            app.isRunning = true;
            app.LearningRateSlider.Enable = 'off';
            app.LearnRateEdit.Enable = 'off';
            
            
            a = 22;
            x = app.xData(a);
            app.hLossStems = stem(app.LossPlot,x,app.yData(a), 'LineWidth', 2);
            
            
            app.Iters= 1;
            app.Loss = abs(x)-app.const;
            
            app.updateLossIteration()
            
            
            % Run one step of the gradient descent algorithm
            % This section can be executed repeatedly to continue the algorithm
            gradF = gradient(app.yData);
            
            x = x - app.LearningRateSlider.Value * gradF(a);
            a = findClosestIndex(app,x,app.xData);
            
            h = stem(app.LossPlot,app.xData(a), app.yData(a), 'LineWidth', 2);
            app.hLossStems = [app.hLossStems;h];
            
            
            app.Loss(end+1) = abs(app.xData(a)-app.const);
            app.Iters = 1:numel(app.Loss);
            app.updateLossIteration()
            
            pause(app.pauseTime)
            
            % Run a whole batch of the gradient descent algorithm
            for ii = 1:50
                gradF = gradient(app.yData);
                
                x = x - app.LearningRateSlider.Value * gradF(a);
                a = findClosestIndex(app,x,app.xData);
                h = stem(app.LossPlot,app.xData(a), app.yData(a), 'LineWidth', 2);
                app.hLossStems = [app.hLossStems;h];
                
                
                app.Loss(end+1) = abs(app.xData(a)-app.const);
                app.Iters = 1:numel(app.Loss);
                app.updateLossIteration()
                
                
                pause(app.pauseTime)
                drawnow
                
            end
            
            app.isRunning = false;
            app.LearningRateSlider.Enable = 'on';
            app.LearnRateEdit.Enable = 'on';
            
        end
        
        function reRun(app)
         
            
           % clear the stems
           delete(app.hLossStems);
           
           
           % run
           app.run
        end
        
        function a = findClosestIndex(app,x_,x)
            [~, a] = min((x_ - x).^2);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initApp(app)
            app.run;
        end

        % Value changed function: LearningRateSlider
        function LearningRateSliderValueChanged(app, event)
            value = app.LearningRateSlider.Value;
            app.LearnRateEdit.Value = value;
            
            app.reRun
        end

        % Value changed function: LearnRateEdit
        function LearnRateEditValueChanged(app, event)
            value = app.LearnRateEdit.Value;
            app.LearningRateSlider.Value = value;
            
            app.reRun
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LossPlot = uiaxes(app.UIFigure);
            title(app.LossPlot, 'Example loss function')
            xlabel(app.LossPlot, 'Weight/bias')
            ylabel(app.LossPlot, 'Loss')
            app.IterationPlot = uiaxes(app.UIFigure);
            title(app.IterationPlot, 'Loss per iteration')
            xlabel(app.IterationPlot, 'iteration')
            ylabel(app.IterationPlot, 'Loss')
            app.LearningRateSliderLabel = uilabel(app.UIFigure);
            app.LearningRateSliderLabel.Text = 'Learning Rate';
            app.LearningRateSlider = uislider(app.UIFigure);
            app.LearningRateSlider.Limits = [0 10];
            app.LearningRateSlider.ValueChangedFcn = createCallbackFcn(app, @LearningRateSliderValueChanged, true);
            app.LearningRateSlider.Value = 0.75;
            app.LearnRateEdit = uieditfield(app.UIFigure, 'numeric');
            app.LearnRateEdit.Limits = [0 10];
            app.LearnRateEdit.ValueChangedFcn = createCallbackFcn(app, @LearnRateEditValueChanged, true);
            app.LearnRateEdit.Value = 0.75;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gradientDescentVisualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end